classdef bearingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        UIAxes                          matlab.ui.control.UIAxes
        BearingSetupPanel               matlab.ui.container.Panel
        RollerDiameterEditFieldLabel    matlab.ui.control.Label
        RollerDiameterEditField         matlab.ui.control.NumericEditField
        PitchDiameterEditFieldLabel     matlab.ui.control.Label
        PitchDiameterEditField          matlab.ui.control.NumericEditField
        ContactAngleDegreeEditFieldLabel  matlab.ui.control.Label
        ContactAngleDegreeEditField     matlab.ui.control.NumericEditField
        NumberofElementsEditFieldLabel  matlab.ui.control.Label
        NumberofElementsEditField       matlab.ui.control.NumericEditField
        SideDropDownLabel               matlab.ui.control.Label
        SideDropDown                    matlab.ui.control.DropDown
        AnalyzerPanel                   matlab.ui.container.Panel
        AnalyzeButton                   matlab.ui.control.Button
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        MinEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        FaultFrequenciesPanel           matlab.ui.container.Panel
        BallEditFieldLabel              matlab.ui.control.Label
        BallEditField                   matlab.ui.control.NumericEditField
        CageEditFieldLabel              matlab.ui.control.Label
        CageEditField                   matlab.ui.control.NumericEditField
        InnerEditFieldLabel             matlab.ui.control.Label
        InnerEditField                  matlab.ui.control.NumericEditField
        OuterEditFieldLabel             matlab.ui.control.Label
        OuterEditField                  matlab.ui.control.NumericEditField
        RevEditFieldLabel               matlab.ui.control.Label
        RevEditField                    matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        fullFileName
        data
        info % Description
        fault
    end

    methods (Access = private)
        % https://www.mathworks.com/help/releases/R2016b/matlab/creating_guis/graphics-support-in-app-designer.html
        function plotBearingFault(app)
            reset(app.UIAxes);
            plot(app.UIAxes, app.fault.freq, app.fault.spec);
            ax = [xlim(app.UIAxes) ylim(app.UIAxes)];
            hold(app.UIAxes, 'on')
            for i = 1:5
                f = app.fault.freqs(i);
                plot(app.UIAxes, [f f], ax(3:4),'LineWidth',2)
            end
            plot(app.UIAxes, app.fault.peaks.loc, app.fault.peaks.val, 'o');
            hold(app.UIAxes, 'off')
            legend(app.UIAxes, 'env', 'cage', 'ball', 'outer', 'inner', '1/rev')
            xlabel(app.UIAxes, 'Hz')
            ylabel(app.UIAxes, 'Gs')
            xlim(app.UIAxes, [0 200]);
            ylim(app.UIAxes, ax(3:4));
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.info = NiceBearing();
            app.RollerDiameterEditField.Value = app.info.rd;
            app.PitchDiameterEditField.Value = app.info.pd;
            app.ContactAngleDegreeEditField.Value = app.info.ca;
            app.NumberofElementsEditField.Value = app.info.ne;
            app.ThresholdEditField.Value = app.info.thr;
            app.MinEditField.Value = app.info.min;
            app.MaxEditField.Value = app.info.max;
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % https://www.mathworks.com/help/matlab/creating_guis/simple-app-designer-example-apps.html
            app.fullFileName = fileLoad();
        end

        % Callback function: ExitMenu, UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Callback function
        function LoadDataButtonPushed(app, event)
            if (app.fullFileName)
                app.data = load(app.fullFileName);
            end
        end

        % Callback function
        function AnalyzeDataButtonPushed(app, event)
            if (app.fullFileName)
                app.fault = calcFaultFreq(app.info, app.data.bearing);
            end
        end

        % Callback function
        function PlotResultsButtonPushed(app, event)
            if (app.fullFileName)
                plotBearingFault(app);
            end
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            if (app.fullFileName)
                app.data = load(app.fullFileName);
                app.fault = calcFaultFreq(app.info, app.data.bearing);
                app.CageEditField.Value = app.fault.freqs(1);
                app.BallEditField.Value = app.fault.freqs(2);
                app.OuterEditField.Value = app.fault.freqs(3);
                app.InnerEditField.Value = app.fault.freqs(4);
                app.RevEditField.Value = app.fault.freqs(5);
                plotBearingFault(app);
            end
        end

        % Value changed function: RollerDiameterEditField
        function RollerDiameterEditFieldValueChanged(app, event)
            value = app.RollerDiameterEditField.Value;
            app.info.rd = value;
        end

        % Value changed function: PitchDiameterEditField
        function PitchDiameterEditFieldValueChanged(app, event)
            value = app.PitchDiameterEditField.Value;
            app.info.pd = value;
        end

        % Value changed function: ContactAngleDegreeEditField
        function ContactAngleDegreeEditFieldValueChanged(app, event)
            value = app.ContactAngleDegreeEditField.Value;
            app.info.ca = value;
        end

        % Value changed function: NumberofElementsEditField
        function NumberofElementsEditFieldValueChanged(app, event)
            value = app.NumberofElementsEditField.Value;
            app.info.ne = value;
        end

        % Value changed function: SideDropDown
        function SideDropDownValueChanged(app, event)
            value = app.SideDropDown.Value;
            if strcmp(value, 'Inner')
                app.info.sd = 1;
            elseif strcmp(value, 'Outer')
                app.info.sd = 2;
            end
        end

        % Callback function
        function OpenFileButtonPushed(app, event)
            app.fullFileName = fileLoad();
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.info.thr = value;
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            value = app.MinEditField.Value;
            app.info.min = value;
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            value = app.MaxEditField.Value;
            app.info.max = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Bearing Analyzer')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.BearingSetupPanel = uipanel(app.UIFigure);
            app.BearingSetupPanel.Title = 'Bearing Setup';
            app.RollerDiameterEditFieldLabel = uilabel(app.BearingSetupPanel);
            app.RollerDiameterEditFieldLabel.Text = 'Roller Diameter';
            app.RollerDiameterEditField = uieditfield(app.BearingSetupPanel, 'numeric');
            app.RollerDiameterEditField.ValueChangedFcn = createCallbackFcn(app, @RollerDiameterEditFieldValueChanged, true);
            app.PitchDiameterEditFieldLabel = uilabel(app.BearingSetupPanel);
            app.PitchDiameterEditFieldLabel.Text = 'Pitch Diameter';
            app.PitchDiameterEditField = uieditfield(app.BearingSetupPanel, 'numeric');
            app.PitchDiameterEditField.ValueChangedFcn = createCallbackFcn(app, @PitchDiameterEditFieldValueChanged, true);
            app.ContactAngleDegreeEditFieldLabel = uilabel(app.BearingSetupPanel);
            app.ContactAngleDegreeEditFieldLabel.Text = 'Contact Angle (Degree)';
            app.ContactAngleDegreeEditField = uieditfield(app.BearingSetupPanel, 'numeric');
            app.ContactAngleDegreeEditField.ValueChangedFcn = createCallbackFcn(app, @ContactAngleDegreeEditFieldValueChanged, true);
            app.NumberofElementsEditFieldLabel = uilabel(app.BearingSetupPanel);
            app.NumberofElementsEditFieldLabel.Text = 'Number of Elements';
            app.NumberofElementsEditField = uieditfield(app.BearingSetupPanel, 'numeric');
            app.NumberofElementsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofElementsEditFieldValueChanged, true);
            app.SideDropDownLabel = uilabel(app.BearingSetupPanel);
            app.SideDropDownLabel.Text = 'Side';
            app.SideDropDown = uidropdown(app.BearingSetupPanel);
            app.SideDropDown.Items = {'Inner', 'Outer'};
            app.SideDropDown.ValueChangedFcn = createCallbackFcn(app, @SideDropDownValueChanged, true);
            app.SideDropDown.Value = 'Outer';
            app.AnalyzerPanel = uipanel(app.UIFigure);
            app.AnalyzerPanel.Title = 'Analyzer';
            app.AnalyzeButton = uibutton(app.AnalyzerPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.ThresholdEditFieldLabel = uilabel(app.AnalyzerPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.AnalyzerPanel, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.MinEditFieldLabel = uilabel(app.AnalyzerPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.AnalyzerPanel, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.MaxEditFieldLabel = uilabel(app.AnalyzerPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.AnalyzerPanel, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.FaultFrequenciesPanel = uipanel(app.UIFigure);
            app.FaultFrequenciesPanel.Title = 'Fault Frequencies';
            app.BallEditFieldLabel = uilabel(app.FaultFrequenciesPanel);
            app.BallEditFieldLabel.Text = 'Ball';
            app.BallEditField = uieditfield(app.FaultFrequenciesPanel, 'numeric');
            app.CageEditFieldLabel = uilabel(app.FaultFrequenciesPanel);
            app.CageEditFieldLabel.Text = 'Cage';
            app.CageEditField = uieditfield(app.FaultFrequenciesPanel, 'numeric');
            app.InnerEditFieldLabel = uilabel(app.FaultFrequenciesPanel);
            app.InnerEditFieldLabel.Text = 'Inner';
            app.InnerEditField = uieditfield(app.FaultFrequenciesPanel, 'numeric');
            app.OuterEditFieldLabel = uilabel(app.FaultFrequenciesPanel);
            app.OuterEditFieldLabel.Text = 'Outer';
            app.OuterEditField = uieditfield(app.FaultFrequenciesPanel, 'numeric');
            app.RevEditFieldLabel = uilabel(app.FaultFrequenciesPanel);
            app.RevEditFieldLabel.Text = '1/Rev';
            app.RevEditField = uieditfield(app.FaultFrequenciesPanel, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = bearingApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end